classdef tremorLabeling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        SyncdataButton    matlab.ui.control.Button
        UITable           matlab.ui.control.Table
        StartlabelButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        measurements % Description
        measurement % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.measurements = sync_measurement_data("Research");
            N = length(app.measurements);
            
            data = {N};
            
            for i=1:N
                data{i, 1} = app.measurements{i}.fileName;
                data{i, 2} = app.measurements{i}.isLabeled;
            end
            
            app.UITable.Data = data;
        end

        % Button pushed function: SyncdataButton
        function SyncdataButtonPushed(app, event)
            app.measurements = sync_measurement_data("Research");
            N = length(app.measurements);
            
            data = {N};
            
            for i=1:N
                data{i, 1} = app.measurements{i}.fileName;
                data{i, 2} = app.measurements{i}.isLabeled;
            end
            
            app.UITable.Data = data;
        end

        % Button pushed function: StartlabelButton
        function StartlabelButtonPushed(app, event)
            if isprop(app, 'measurement')
                app.measurement.fileName
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.measurement = app.measurements{event.Indices(1)};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SyncdataButton = uibutton(app.UIFigure, 'push');
            app.SyncdataButton.ButtonPushedFcn = createCallbackFcn(app, @SyncdataButtonPushed, true);
            app.SyncdataButton.Text = 'Sync data';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Filename'; 'Is labeled'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.StartlabelButton = uibutton(app.UIFigure, 'push');
            app.StartlabelButton.ButtonPushedFcn = createCallbackFcn(app, @StartlabelButtonPushed, true);
            app.StartlabelButton.Text = 'Start label';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tremorLabeling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end